classdef GCodeBefehle < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GCodeBefehleUIFigure            matlab.ui.Figure
        DoneButton                      matlab.ui.control.Button
        LaserONEditFieldLabel           matlab.ui.control.Label
        LaserONEditField                matlab.ui.control.EditField
        LaserOFFEditFieldLabel          matlab.ui.control.Label
        LaserOFFEditField               matlab.ui.control.EditField
        ScanbefehlEditFieldLabel        matlab.ui.control.Label
        ScanbefehlEditField             matlab.ui.control.EditField
        EilbefehlEditFieldLabel         matlab.ui.control.Label
        EilbefehlEditField              matlab.ui.control.EditField
        LaserleistungEditFieldLabel     matlab.ui.control.Label
        LaserleistungEditField          matlab.ui.control.EditField
        ScangeschwindigkeitEditFieldLabel  matlab.ui.control.Label
        ScangeschwindigkeitEditField    matlab.ui.control.EditField
        waitmodeEditFieldLabel          matlab.ui.control.Label
        G359EditField                   matlab.ui.control.EditField
        PausezwPixelreihensSpinnerLabel  matlab.ui.control.Label
        PausezwPixelreihenSpinner       matlab.ui.control.Spinner
        PixelreiheHeaderEditFieldLabel  matlab.ui.control.Label
        CriticalStartEditField          matlab.ui.control.EditField
        PixelreiheFooterEditFieldLabel  matlab.ui.control.Label
        CriticalEndEditField            matlab.ui.control.EditField
        CodeFooterEditFieldLabel        matlab.ui.control.Label
        CodeFooterEditField             matlab.ui.control.EditField
        EntschleunigungLabel            matlab.ui.control.Label
        VelocityONEditField             matlab.ui.control.EditField
        PausezwParameterwechselsSpinnerLabel  matlab.ui.control.Label
        PausezwParameterwechselSpinner  matlab.ui.control.Spinner
        optAchseXEditFieldLabel         matlab.ui.control.Label
        optAchseXEditField              matlab.ui.control.EditField
        optAchseYEditFieldLabel         matlab.ui.control.Label
        optAchseYEditField              matlab.ui.control.EditField
        WartenEditFieldLabel            matlab.ui.control.Label
        WartenEditField                 matlab.ui.control.EditField
    end


    methods (Access = private)

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            GCode = cell(16,1);
            GCode{1,1} = app.LaserONEditField.Value;
            GCode{2,1} = app.LaserOFFEditField.Value;
            GCode{3,1} = app.ScanbefehlEditField.Value;
            GCode{4,1} = app.EilbefehlEditField.Value;
            GCode{5,1} = app.LaserleistungEditField.Value;
            GCode{6,1} = app.ScangeschwindigkeitEditField.Value;
            GCode{7,1} = app.G359EditField.Value;
            GCode{8,1} = num2str(app.PausezwPixelreihenSpinner.Value);
            GCode{9,1} = app.CriticalStartEditField.Value;
            GCode{10,1} = app.CriticalEndEditField.Value;
            GCode{11,1} = app.CodeFooterEditField.Value;
            GCode{12,1} = app.VelocityONEditField.Value;
            GCode{13,1} = num2str(app.PausezwParameterwechselSpinner.Value);
            GCode{14,1} = app.optAchseXEditField.Value;
            GCode{15,1} = app.optAchseYEditField.Value;
            GCode{16,1} = app.WartenEditField.Value;
            Collector(GrayScales_GUI,GCode);
            delete(app);
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GCodeBefehleUIFigure = uifigure;
            app.GCodeBefehleUIFigure.Name = 'G Code Befehle';
            app.DoneButton = uibutton(app.GCodeBefehleUIFigure, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            app.LaserONEditFieldLabel = uilabel(app.GCodeBefehleUIFigure);
            app.LaserONEditFieldLabel.Text = 'Laser ON';
            app.LaserONEditField = uieditfield(app.GCodeBefehleUIFigure, 'text');
            app.LaserONEditField.Value = 'L1';
            app.LaserOFFEditFieldLabel = uilabel(app.GCodeBefehleUIFigure);
            app.LaserOFFEditFieldLabel.Text = 'Laser OFF';
            app.LaserOFFEditField = uieditfield(app.GCodeBefehleUIFigure, 'text');
            app.LaserOFFEditField.Value = 'L0';
            app.ScanbefehlEditFieldLabel = uilabel(app.GCodeBefehleUIFigure);
            app.ScanbefehlEditFieldLabel.Text = 'Scanbefehl';
            app.ScanbefehlEditField = uieditfield(app.GCodeBefehleUIFigure, 'text');
            app.ScanbefehlEditField.Value = 'G08 G01';
            app.EilbefehlEditFieldLabel = uilabel(app.GCodeBefehleUIFigure);
            app.EilbefehlEditFieldLabel.Text = 'Eilbefehl';
            app.EilbefehlEditField = uieditfield(app.GCodeBefehleUIFigure, 'text');
            app.EilbefehlEditField.Value = 'G00';
            app.LaserleistungEditFieldLabel = uilabel(app.GCodeBefehleUIFigure);
            app.LaserleistungEditFieldLabel.Text = 'Laserleistung';
            app.LaserleistungEditField = uieditfield(app.GCodeBefehleUIFigure, 'text');
            app.LaserleistungEditField.Value = 'P=';
            app.ScangeschwindigkeitEditFieldLabel = uilabel(app.GCodeBefehleUIFigure);
            app.ScangeschwindigkeitEditFieldLabel.Text = 'Scangeschwindigkeit';
            app.ScangeschwindigkeitEditField = uieditfield(app.GCodeBefehleUIFigure, 'text');
            app.ScangeschwindigkeitEditField.Value = 'F';
            app.waitmodeEditFieldLabel = uilabel(app.GCodeBefehleUIFigure);
            app.waitmodeEditFieldLabel.Text = 'wait mode';
            app.G359EditField = uieditfield(app.GCodeBefehleUIFigure, 'text');
            app.G359EditField.Value = 'G359';
            app.PausezwPixelreihensSpinnerLabel = uilabel(app.GCodeBefehleUIFigure);
            app.PausezwPixelreihensSpinnerLabel.Text = 'Pause zw. Pixelreihen [s]';
            app.PausezwPixelreihenSpinner = uispinner(app.GCodeBefehleUIFigure);
            app.PausezwPixelreihenSpinner.Step = 0.1;
            app.PausezwPixelreihenSpinner.Limits = [0 10];
            app.PausezwPixelreihenSpinner.Value = 0.5;
            app.PixelreiheHeaderEditFieldLabel = uilabel(app.GCodeBefehleUIFigure);
            app.PixelreiheHeaderEditFieldLabel.Text = 'Pixelreihe Header';
            app.CriticalStartEditField = uieditfield(app.GCodeBefehleUIFigure, 'text');
            app.CriticalStartEditField.Value = 'CRITICAL START';
            app.PixelreiheFooterEditFieldLabel = uilabel(app.GCodeBefehleUIFigure);
            app.PixelreiheFooterEditFieldLabel.Text = 'Pixelreihe Footer';
            app.CriticalEndEditField = uieditfield(app.GCodeBefehleUIFigure, 'text');
            app.CriticalEndEditField.Value = 'CRITICAL END';
            app.CodeFooterEditFieldLabel = uilabel(app.GCodeBefehleUIFigure);
            app.CodeFooterEditFieldLabel.Text = 'Code Footer';
            app.CodeFooterEditField = uieditfield(app.GCodeBefehleUIFigure, 'text');
            app.CodeFooterEditField.Value = 'END PROGRAM';
            app.EntschleunigungLabel = uilabel(app.GCodeBefehleUIFigure);
            app.EntschleunigungLabel.Text = 'Entschleunigung?';
            app.VelocityONEditField = uieditfield(app.GCodeBefehleUIFigure, 'text');
            app.VelocityONEditField.Value = 'VELOCITY OFF';
            app.PausezwParameterwechselsSpinnerLabel = uilabel(app.GCodeBefehleUIFigure);
            app.PausezwParameterwechselsSpinnerLabel.Text = 'Pause zw. Parameterwechsel [s]';
            app.PausezwParameterwechselSpinner = uispinner(app.GCodeBefehleUIFigure);
            app.PausezwParameterwechselSpinner.Step = 0.1;
            app.PausezwParameterwechselSpinner.Limits = [0 100];
            app.PausezwParameterwechselSpinner.Value = 0.5;
            app.optAchseXEditFieldLabel = uilabel(app.GCodeBefehleUIFigure);
            app.optAchseXEditFieldLabel.Text = 'opt. Achse [X]';
            app.optAchseXEditField = uieditfield(app.GCodeBefehleUIFigure, 'text');
            app.optAchseXEditField.Value = 'U';
            app.optAchseYEditFieldLabel = uilabel(app.GCodeBefehleUIFigure);
            app.optAchseYEditFieldLabel.Text = 'opt. Achse [Y]';
            app.optAchseYEditField = uieditfield(app.GCodeBefehleUIFigure, 'text');
            app.optAchseYEditField.Value = 'V';
            app.WartenEditFieldLabel = uilabel(app.GCodeBefehleUIFigure);
            app.WartenEditFieldLabel.Text = 'Warten';
            app.WartenEditField = uieditfield(app.GCodeBefehleUIFigure, 'text');
            app.WartenEditField.Value = 'DWELL';
        end
    end

    methods (Access = public)

        % Construct app
        function app = GCodeBefehle

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GCodeBefehleUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GCodeBefehleUIFigure)
        end
    end
end