classdef GrayScales_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LasermarkerCAMGSUIFigure     matlab.ui.Figure
        UIAxes2                      matlab.ui.control.UIAxes
        FehlerdiffusionCheckBox      matlab.ui.control.CheckBox
        NameEditFieldLabel           matlab.ui.control.Label
        Name                         matlab.ui.control.EditField
        DateiQuelle                  matlab.ui.control.EditField
        NCCode                       matlab.ui.control.Button
        Browser                      matlab.ui.control.Button
        Slider                       matlab.ui.control.Slider
        Label6                       matlab.ui.control.Label
        PixelgrssemmLabel            matlab.ui.control.Label
        Pixelgroesse                 matlab.ui.control.NumericEditField
        Label10                      matlab.ui.control.Label
        Linienabstand                matlab.ui.control.NumericEditField
        MaxLaserleistungVLabel       matlab.ui.control.Label
        MaxLaserleistung             matlab.ui.control.NumericEditField
        Bildgroesse                  matlab.ui.control.EditField
        BildgroesseButton            matlab.ui.control.Button
        AnzahlGraustufenLabel        matlab.ui.control.Label
        Anzahl_Graustufen            matlab.ui.control.NumericEditField
        SkywritelngemmLabel_2        matlab.ui.control.Label
        Skywritelaenge               matlab.ui.control.NumericEditField
        ScangeschwindigkeitmmsLabel  matlab.ui.control.Label
        Scangeschwindigkeit          matlab.ui.control.NumericEditField
        ZielOrdnerButton             matlab.ui.control.Button
        ZielOrdner                   matlab.ui.control.EditField
        QuellOrdner                  matlab.ui.control.Button
        GCodeBefehleButton           matlab.ui.control.Button
    end

    
    properties (Access = public)
        Listener = false;
    end
    
    methods (Access = public)
        
        function Collector(app, data)
            GCode = cell(16,1);
            for i = 1:1:16
                GCode{i,1} = data{i,1};
            end
            setappdata(0,'GCode',GCode);
            app.Listener = true;
        end
 
%         function [FDC, Name, DQ, Slider, PG, LS, MLL, BG, AGS, SL, SG, ZO] = Setter(app)
%             FDC = app.FehlerdiffusionCheckBox.Value;
%             Name = app.Name.Value;
%             DQ = app.DateiQuelle.Value;
%             Slider = app.Slider.Value;
%             PG = app.Pixelgroesse.Value;
%             LS = app.Linienabstand.Value;
%             MLL = app.MaxLaserleistung.Value;
%             BG = app.Bildgroesse.Value;
%             AGS = app.Anzahl_Graustufen;
%             SL = app.Skywritelaenge;
%             SG = app.Scangeschwindigkeit.Value;
%             ZO = app.Zielordner.Value;
%         end
    end 

    methods (Access = private)

        % Button pushed function: Browser
        function Browser_CBF(app, event)

            source = app.DateiQuelle.Value;
            [FileName,PathName] = uigetfile([source,'/*.jpg'],'Auswahl der Bitmap-Datei');
            Pfad = [PathName,FileName];
            set(app.DateiQuelle,'Value',Pfad);
            setappdata(0,'fd',app.FehlerdiffusionCheckBox.Value);
            setappdata(0,'AG',app.Anzahl_Graustufen.Value);
            setappdata(0,'f',app.Slider.Value);
            setappdata(0,'pg',app.Pixelgroesse.Value);
            setappdata(0,'Pfad',app.DateiQuelle.Value);
            
            
            fd = getappdata(0,'fd');
            AG = getappdata(0,'AG');
            Pfad = getappdata(0,'Pfad');
            f = getappdata(0,'f');
                f_new = round(f);
                set(app.Slider,'Value',f_new);
                setappdata(0,'f',f_new);
                getappdata(0,'f');
            pg = getappdata(0,'pg');
            
            
            %% TIFF Prüfung    
            if Pfad(end) == 'f'
                t = true;
            else
                t = false;
            end
            
            %% Bildbearbeitung_GS
            [C,map]=Bildbearbeitung_GS(t,f,Pfad,fd,AG);
            [x,y] = size(C);
            imshow(C(:,2:1:(y-1)),map,'Parent',app.UIAxes2); 
            setappdata(0,'C',C);
            setappdata(0,'map',map);
            setappdata(0,'x',x);
            setappdata(0,'y',y);
            
            %% Bildgroesse
            if ~isempty(Pfad)
                set(app.Bildgroesse,'Value',[num2str((y-2)*pg*f),'mm x ',num2str(x*pg*f),'mm']);
            else
            end 
        end

        % Value changed function: FehlerdiffusionCheckBox
        function FehlerdiffusionCheckBox_CBF(app, event)
          setappdata(0,'fd',app.FehlerdiffusionCheckBox.Value);
          Pfad = getappdata(0,'Pfad');
          fd = getappdata(0,'fd');
          AG = getappdata(0,'AG');
          f = getappdata(0,'f');
          pg = getappdata(0,'pg');
          x = getappdata(0,'x');
          y = getappdata(0,'y');

          
          if ~isempty(Pfad)  
            if Pfad(end) == 'f'
                t = true;
            else
                t = false;
            end
            [C,map]=Bildbearbeitung_GS(t,f,Pfad,fd,AG);
            imshow(C(:,2:1:(y-1)),map,'Parent',app.UIAxes2); 
            
            %% Bildgroesse
            if ~isempty(Pfad)
                set(app.Bildgroesse,'Value',[num2str((y-2)*pg*f),'mm x ',num2str(x*pg*f),'mm']);
            end 
          end
        end

        % Value changed function: Slider
        function SliderValue_CBF(app, event)
            Pfad = getappdata(0,'Pfad');
            fd = getappdata(0,'fd');
            AG = getappdata(0,'AG');
            setappdata(0,'f',app.Slider.Value);
                f = getappdata(0,'f');
                f_new = f;
                f = round(f_new);
                set(app.Slider,'Value',f);
                setappdata(0,'f',f);
            pg = getappdata(0,'pg');

            if ~isempty(Pfad)     
                if Pfad(end) == 'f'
                    t = true;
                else
                    t = false;
                end
                [C,map]=Bildbearbeitung_GS(t,f,Pfad,fd,AG);
                [x,y] = size(C);
                setappdata(0,'x',x);
                setappdata(0,'y',y);
                imshow(C(:,2:1:(y-1)),map,'Parent',app.UIAxes2);  
                
                %% Bildgroesse
                if ~isempty(Pfad)
                    set(app.Bildgroesse,'Value',[num2str((y-2)*pg*f),'mm x ',num2str(x*pg*f),'mm']);
                end 
            end
        end

        % Button pushed function: BildgroesseButton
        function BildgroesseButton_CBF(app, event)
            Pfad = getappdata(0,'Pfad');
            f = getappdata(0,'f');
            AG = getappdata(0,'AG');
            fd = getappdata(0,'fd');
            x = getappdata(0,'x');
            y = getappdata(0,'y');
            pg = getappdata(0,'pg');

            
            if ~isempty(Pfad)     
                if Pfad(end) == 'f'
                    t = true;
                else
                    t = false;
                end
                [C,map]=Bildbearbeitung_GS(t,f,Pfad,fd,AG);
                imshow(C(:,2:1:(y-1)),map,'Parent',app.UIAxes2); 
                
                %% Bildgroesse
                if ~isempty(Pfad)
                    set(app.Bildgroesse,'Value',[num2str((y-2)*pg*f),'mm x ',num2str(x*pg*f),'mm']);
                end 
            end
        end

        % Value changed function: Anzahl_Graustufen
        function Anzahl_Graustufen_CBF(app, event)
            setappdata(0,'AG',app.Anzahl_Graustufen.Value);
                AG = getappdata(0,'AG');
                AG_new = round(AG);
                set(app.Anzahl_Graustufen,'Value',AG_new);
                setappdata(0,'AG',AG_new);
                AG = getappdata(0,'AG');
            Pfad = getappdata(0,'Pfad');
            f = getappdata(0,'f');
            fd = getappdata(0,'fd');
            x = getappdata(0,'x');
            y = getappdata(0,'y');
            pg = getappdata(0,'pg');
            
            if ~isempty(Pfad)     
                if Pfad(end) == 'f'
                    t = true;
                else
                    t = false;
                end
                
                [C,map]=Bildbearbeitung_GS(t,f,Pfad,fd,AG);
                imshow(C(:,2:1:(y-1)),map,'Parent',app.UIAxes2); 
                
                %% Bildgroesse
                if ~isempty(Pfad)
                    set(app.Bildgroesse,'Value',[num2str((y-2)*pg*f),'mm x ',num2str(x*pg*f),'mm']);
                end 
            end
            
        end

        % Button pushed function: NCCode
        function NCCode_CBF(app, event)

            if app.Listener == true
                GCode = getappdata(0,'GCode');
            else
            GCode = cell(16,1);
                GCode{1,1} = 'L1';
                GCode{2,1} = 'L0';
                GCode{3,1} = 'G08 G01';
                GCode{4,1} = 'G00';
                GCode{5,1} = 'P=';
                GCode{6,1} = 'F';
                GCode{7,1} = 'G359';
                GCode{8,1} = '0.5';
                GCode{9,1} = 'CRITICAL START';
                GCode{10,1} = 'CRITICAL END';
                GCode{11,1} = 'END PROGRAM';
                GCode{12,1} = 'VELOCITY ON';
                GCode{13,1} = '3';
                GCode{14,1} = 'U';
                GCode{15,1} = 'V';
                GCode{16,1} = 'DWELL';
            end
            
            
            Pfad = getappdata(0,'Pfad');
            AG = getappdata(0,'AG');
            pg = getappdata(0,'pg');
            f = getappdata(0,'f');
            fd = getappdata(0,'fd');
            x = getappdata(0,'x');
            y = getappdata(0,'y');
            Ziel = getappdata(0,'PathZielOrdner');
            
            if ~isempty(Pfad) || ~isempty(Ziel)

                if Pfad(end) == 'f'
                    t = true;
                else
                    t = false;
                end
                P = cell(AG,1);
                Pmax = app.MaxLaserleistung.Value;
                for i = 1:1:AG
                    P{i,1} = Pmax-Pmax/AG*(i-1);
                end
                Vs = app.Scangeschwindigkeit.Value;
                LS = app.Linienabstand.Value;
                SW = app.Skywritelaenge.Value;
                [C,~]=Bildbearbeitung_GS(t,f,Pfad,fd,AG);
                [bitmap] = Farbzuordnung_GS(x,y-2,C,AG);
                FileName = app.Name.Value;
                NC_Berechnung_GS(P,Vs,bitmap,x,y-2,pg,LS,SW,FileName,AG,f,Ziel,GCode)
            end
        end

        % Value changed function: Pixelgroesse
        function Pixelgroesse_CBF(app, event)
            setappdata(0,'pg',app.Pixelgroesse.Value);
            pg = getappdata(0,'pg');
            x = getappdata(0,'x');
            y = getappdata(0,'y');
            Pfad = getappdata(0,'Pfad');
            f = getappdata(0,'f');

            if pg<app.Linienabstand.Value
                set(app.Linienabstand,'Value',pg);
            end
            
            if ~isempty(Pfad)
                set(app.Bildgroesse,'Value',[num2str((y-2)*pg*f),'mm x ',num2str(x*pg*f),'mm']);
            end 
            
        end

        % Value changed function: Linienabstand
        function Linienabstand_CBF(app, event)
            pg = getappdata(0,'pg');
            if pg<app.Linienabstand.Value
                set(app.Linienabstand,'Value',pg);
            end
        end

        % Button pushed function: ZielOrdnerButton
        function ZielOrdner_CBF(app, event)
            source = app.ZielOrdner.Value;
            [PathZielOrdner] = uigetdir(source,'Zielordner');
            setappdata(0,'PathZielOrdner',PathZielOrdner);
            set(app.ZielOrdner,'Value',PathZielOrdner);
        end

        % Button pushed function: QuellOrdner
        function QuellOrdner_CBF(app, event)
            source = app.DateiQuelle.Value;
            [PathQuellOrdner] = uigetdir(source,'Quellordner');
            setappdata(0,'PathQuellOrdner',PathQuellOrdner);
            set(app.DateiQuelle,'Value',PathQuellOrdner);
        end

        % Button pushed function: GCodeBefehleButton
        function GCodeBefehle_CBF(app, event)
            GCodeBefehle; 
            delete(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LasermarkerCAMGSUIFigure = uifigure;
            app.LasermarkerCAMGSUIFigure.Name = 'Lasermarker CAM GS';
            app.UIAxes2 = uiaxes(app.LasermarkerCAMGSUIFigure);
            app.UIAxes2.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.FehlerdiffusionCheckBox = uicheckbox(app.LasermarkerCAMGSUIFigure);
            app.FehlerdiffusionCheckBox.ValueChangedFcn = createCallbackFcn(app, @FehlerdiffusionCheckBox_CBF, true);
            app.FehlerdiffusionCheckBox.Text = 'Fehlerdiffusion';
            app.FehlerdiffusionCheckBox.Value = true;
            app.NameEditFieldLabel = uilabel(app.LasermarkerCAMGSUIFigure);
            app.NameEditFieldLabel.Text = 'Name';
            app.Name = uieditfield(app.LasermarkerCAMGSUIFigure, 'text');
            app.Name.Value = 'Name';
            app.DateiQuelle = uieditfield(app.LasermarkerCAMGSUIFigure, 'text');
            app.DateiQuelle.Value = 'C:\Users';
            app.NCCode = uibutton(app.LasermarkerCAMGSUIFigure, 'push');
            app.NCCode.ButtonPushedFcn = createCallbackFcn(app, @NCCode_CBF, true);
            app.NCCode.Text = 'NC Code';
            app.Browser = uibutton(app.LasermarkerCAMGSUIFigure, 'push');
            app.Browser.ButtonPushedFcn = createCallbackFcn(app, @Browser_CBF, true);
            app.Browser.Text = 'Original Datei';
            app.Slider = uislider(app.LasermarkerCAMGSUIFigure);
            app.Slider.Limits = [1 10];
            app.Slider.MajorTicks = [1 2 3 4 5 6 7 8 9 10];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValue_CBF, true);
            app.Slider.MinorTicks = [1 2 3 4 5 6 7 8 9 10];
            app.Slider.Value = 1;
            app.Label6 = uilabel(app.LasermarkerCAMGSUIFigure);
            app.Label6.Text = 'Reduktionsfaktor';
            app.PixelgrssemmLabel = uilabel(app.LasermarkerCAMGSUIFigure);
            app.PixelgrssemmLabel.Text = 'Pixelgrösse [mm]';
            app.Pixelgroesse = uieditfield(app.LasermarkerCAMGSUIFigure, 'numeric');
            app.Pixelgroesse.ValueChangedFcn = createCallbackFcn(app, @Pixelgroesse_CBF, true);
            app.Pixelgroesse.Value = 0.05;
            app.Label10 = uilabel(app.LasermarkerCAMGSUIFigure);
            app.Label10.Text = 'Linienabstand [mm]';
            app.Linienabstand = uieditfield(app.LasermarkerCAMGSUIFigure, 'numeric');
            app.Linienabstand.ValueChangedFcn = createCallbackFcn(app, @Linienabstand_CBF, true);
            app.Linienabstand.Value = 0.01;
            app.MaxLaserleistungVLabel = uilabel(app.LasermarkerCAMGSUIFigure);
            app.MaxLaserleistungVLabel.Text = 'Max. Laserleistung [V]';
            app.MaxLaserleistung = uieditfield(app.LasermarkerCAMGSUIFigure, 'numeric');
            app.MaxLaserleistung.Value = 3;
            app.Bildgroesse = uieditfield(app.LasermarkerCAMGSUIFigure, 'text');
            app.BildgroesseButton = uibutton(app.LasermarkerCAMGSUIFigure, 'push');
            app.BildgroesseButton.ButtonPushedFcn = createCallbackFcn(app, @BildgroesseButton_CBF, true);
            app.BildgroesseButton.Text = 'Bildgröße';
            app.AnzahlGraustufenLabel = uilabel(app.LasermarkerCAMGSUIFigure);
            app.AnzahlGraustufenLabel.Text = {'Anzahl Graustufen'; ''};
            app.Anzahl_Graustufen = uieditfield(app.LasermarkerCAMGSUIFigure, 'numeric');
            app.Anzahl_Graustufen.ValueChangedFcn = createCallbackFcn(app, @Anzahl_Graustufen_CBF, true);
            app.Anzahl_Graustufen.Value = 3;
            app.SkywritelngemmLabel_2 = uilabel(app.LasermarkerCAMGSUIFigure);
            app.SkywritelngemmLabel_2.Text = 'Skywritelänge [mm]';
            app.Skywritelaenge = uieditfield(app.LasermarkerCAMGSUIFigure, 'numeric');
            app.Skywritelaenge.Value = 0.1;
            app.ScangeschwindigkeitmmsLabel = uilabel(app.LasermarkerCAMGSUIFigure);
            app.ScangeschwindigkeitmmsLabel.Text = 'Scangeschwindigkeit [mm/s]';
            app.Scangeschwindigkeit = uieditfield(app.LasermarkerCAMGSUIFigure, 'numeric');
            app.Scangeschwindigkeit.Value = 500;
            app.ZielOrdnerButton = uibutton(app.LasermarkerCAMGSUIFigure, 'push');
            app.ZielOrdnerButton.ButtonPushedFcn = createCallbackFcn(app, @ZielOrdner_CBF, true);
            app.ZielOrdnerButton.Text = 'Zielordner';
            app.ZielOrdner = uieditfield(app.LasermarkerCAMGSUIFigure, 'text');
            app.ZielOrdner.Value = 'C:\Users';
            app.QuellOrdner = uibutton(app.LasermarkerCAMGSUIFigure, 'push');
            app.QuellOrdner.ButtonPushedFcn = createCallbackFcn(app, @QuellOrdner_CBF, true);
            app.QuellOrdner.Text = 'Quellordner';
            app.GCodeBefehleButton = uibutton(app.LasermarkerCAMGSUIFigure, 'push');
            app.GCodeBefehleButton.ButtonPushedFcn = createCallbackFcn(app, @GCodeBefehle_CBF, true);
            app.GCodeBefehleButton.Text = 'G-Code Befehle';
        end
    end

    methods (Access = public)

        % Construct app
        function app = GrayScales_GUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.LasermarkerCAMGSUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LasermarkerCAMGSUIFigure)
        end
    end
end