classdef BW_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        Slider                   matlab.ui.control.Slider
        UIAxes2                  matlab.ui.control.UIAxes
        Label6                   matlab.ui.control.Label
        Label7                   matlab.ui.control.Label
        Label8                   matlab.ui.control.Label
        Label9                   matlab.ui.control.Label
        Label10                  matlab.ui.control.Label
        NCCode                   matlab.ui.control.Button
        DateiQuelle              matlab.ui.control.EditField
        Scangeschwindigkeit      matlab.ui.control.NumericEditField
        Laserleistung            matlab.ui.control.NumericEditField
        Pixelgroesse             matlab.ui.control.NumericEditField
        Linienabstand            matlab.ui.control.NumericEditField
        Browser                  matlab.ui.control.Button
        NameEditFieldLabel       matlab.ui.control.Label
        Name                     matlab.ui.control.EditField
        SkywritelngemmLabel      matlab.ui.control.Label
        Skywritelaenge           matlab.ui.control.NumericEditField
        Bildgroesse              matlab.ui.control.EditField
        BildgroesseButton        matlab.ui.control.Button
        FehlerdiffusionCheckBox  matlab.ui.control.CheckBox
        ZielOrdner               matlab.ui.control.EditField
        ZielordnerButton         matlab.ui.control.Button
    end

    methods (Access = private)

        % Value changed function: Slider
        function Aufloesung_CBF(app, event)
            fd = app.FehlerdiffusionCheckBox.Value;
            if isempty(app.DateiQuelle.Value)
                value_x = app.Slider.Value;
                value = round(value_x);
                set(app.Slider,'Value',value);
            else 
                if app.DateiQuelle.Value(end) == 'f'
                    t = true;
                else
                    t = false;
                end
                
                value_x = app.Slider.Value;
                value = round(value_x);
                set(app.Slider,'Value',value);
                Pfad = get(app.DateiQuelle);
                [~,C]=Bildbearbeitung(t,value,Pfad.Value,fd);
                [x,y] = size(C);
                imshow(C(:,2:1:y-1),'Parent',app.UIAxes2);
                p = app.Pixelgroesse.Value;
                set(app.Bildgroesse,'Value',[num2str((y-2)*p*value),'mm x ',num2str(x*p*value),'mm']);
            end
        end

        % Button pushed function: Browser
        function Browser_CBF(app, event)
            [FileName,PathName] = uigetfile('C:\Users/*.jpg','Auswahl der Bitmap-Datei');
            Pfad = [PathName,FileName];
            set(app.DateiQuelle,'Value',Pfad);
            fd = app.FehlerdiffusionCheckBox.Value;
            
            if app.DateiQuelle.Value(end) == 'f'
                t = true;
            else
                t = false;
            end
            
            value_x = app.Slider.Value;
            value = round(value_x);
            set(app.Slider,'Value',value);
            Pfad = app.DateiQuelle;
            [~,C]=Bildbearbeitung(t,value,Pfad.Value,fd);
            [x,y] = size(C);
            imshow(C(:,2:1:y-1),'Parent',app.UIAxes2); 
            p = app.Pixelgroesse.Value;
            set(app.Bildgroesse,'Value',[num2str((y-2)*p*value),'mm x ',num2str(x*p*value),'mm']);
        end

        % Button pushed function: NCCode
        function NCCode_CBF(app, event)
            if ~isempty(app.DateiQuelle.Value) && ~isempty(app.ZielOrdner.Value)
                fd = app.FehlerdiffusionCheckBox.Value;
                % auf .tiff überprüfen
                if app.DateiQuelle.Value(end) == 'f'
                    t = true;
                else
                    t = false;
                end
                
                value = app.Slider.Value;
                Pfad = app.DateiQuelle.Value;
                [~,C]=Bildbearbeitung(t,value,Pfad,fd);
                SW = app.Skywritelaenge.Value;
                P = app.Laserleistung.Value;
                Vs = app.Scangeschwindigkeit.Value;
                LS = app.Linienabstand.Value;
                groesse = app.Pixelgroesse.Value;
                FileName = app.Name.Value;
                NC_Code_Berechnung_app(P,Vs,C,groesse,LS,SW,FileName,value,app.ZielOrdner.Value)
            end
        end

        % Value changed function: Pixelgroesse
        function Pixelgroesse_CBF(app, event)
            if ~isempty(app.DateiQuelle.Value)
                fd = app.FehlerdiffusionCheckBox.Value;
                % auf .tiff überprüfen
                if app.DateiQuelle.Value(end) == 'f'
                    t = true;
                else
                    t = false;
                end
                
                value = app.Slider.Value;
                Pfad = app.DateiQuelle.Value;
                [~,C]=Bildbearbeitung(t,value,Pfad,fd);
                [x,y] = size(C);
                p = app.Pixelgroesse.Value;
                f = app.Slider.Value;
                set(app.Bildgroesse,'Value',[num2str((y-2)*p*f),'mm x ',num2str(x*p*f),'mm']);
            end 
        end

        % Value changed function: FehlerdiffusionCheckBox
        function Fehlerdiffusion_CBF(app, event)
            if ~isempty(app.DateiQuelle.Value)
                fd = app.FehlerdiffusionCheckBox.Value;
                value = app.Slider.Value;
                Pfad = app.DateiQuelle.Value;
                % auf .tiff überprüfen
                if app.DateiQuelle.Value(end) == 'f'
                    t = true;
                else
                    t = false;
                end
                [~,C]=Bildbearbeitung(t,value,Pfad,fd);
                [~,y] = size(C);
                imshow(C(:,2:1:y-1),'Parent',app.UIAxes2);
            end
        end

        % Button pushed function: ZielordnerButton
        function ZielordnerButtonPushed(app, event)
            app.ZielOrdner.Value = [uigetdir('C:\Users\','Zielordner wählen'),'\'];
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [1 10];
            app.Slider.MajorTicks = [1 2 3 4 5 6 7 8 9 10];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @Aufloesung_CBF, true);
            app.Slider.MinorTicks = [1 2 3 4 5 6 7 8 9 10];
            app.Slider.Value = 1;
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'bearbeitetes Bild')
            app.UIAxes2.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.Label6 = uilabel(app.UIFigure);
            app.Label6.Text = 'Reduktionsfaktor';
            app.Label7 = uilabel(app.UIFigure);
            app.Label7.Text = 'Scangeschwindigkeit [mm/s]';
            app.Label8 = uilabel(app.UIFigure);
            app.Label8.Text = 'Laserleistung [V]';
            app.Label9 = uilabel(app.UIFigure);
            app.Label9.Text = 'Pixelgrösse';
            app.Label10 = uilabel(app.UIFigure);
            app.Label10.Text = 'Linienabstand [mm]';
            app.NCCode = uibutton(app.UIFigure, 'push');
            app.NCCode.ButtonPushedFcn = createCallbackFcn(app, @NCCode_CBF, true);
            app.NCCode.Text = 'NC Code';
            app.DateiQuelle = uieditfield(app.UIFigure, 'text');
            app.Scangeschwindigkeit = uieditfield(app.UIFigure, 'numeric');
            app.Scangeschwindigkeit.Value = 100;
            app.Laserleistung = uieditfield(app.UIFigure, 'numeric');
            app.Laserleistung.Value = 1;
            app.Pixelgroesse = uieditfield(app.UIFigure, 'numeric');
            app.Pixelgroesse.ValueChangedFcn = createCallbackFcn(app, @Pixelgroesse_CBF, true);
            app.Pixelgroesse.Value = 0.05;
            app.Linienabstand = uieditfield(app.UIFigure, 'numeric');
            app.Linienabstand.Value = 0.01;
            app.Browser = uibutton(app.UIFigure, 'push');
            app.Browser.ButtonPushedFcn = createCallbackFcn(app, @Browser_CBF, true);
            app.Browser.Text = 'Original Datei';
            app.NameEditFieldLabel = uilabel(app.UIFigure);
            app.NameEditFieldLabel.Text = 'Name';
            app.Name = uieditfield(app.UIFigure, 'text');
            app.Name.Value = 'Name';
            app.SkywritelngemmLabel = uilabel(app.UIFigure);
            app.SkywritelngemmLabel.Text = 'Skywritelänge [mm]';
            app.Skywritelaenge = uieditfield(app.UIFigure, 'numeric');
            app.Skywritelaenge.Value = 0.1;
            app.Bildgroesse = uieditfield(app.UIFigure, 'text');
            app.BildgroesseButton = uibutton(app.UIFigure, 'push');
            app.BildgroesseButton.Text = 'Bildgröße';
            app.FehlerdiffusionCheckBox = uicheckbox(app.UIFigure);
            app.FehlerdiffusionCheckBox.ValueChangedFcn = createCallbackFcn(app, @Fehlerdiffusion_CBF, true);
            app.FehlerdiffusionCheckBox.Text = 'Fehlerdiffusion';
            app.FehlerdiffusionCheckBox.Value = true;
            app.ZielOrdner = uieditfield(app.UIFigure, 'text');
            app.ZielordnerButton = uibutton(app.UIFigure, 'push');
            app.ZielordnerButton.ButtonPushedFcn = createCallbackFcn(app, @ZielordnerButtonPushed, true);
            app.ZielordnerButton.Text = 'Zielordner';
        end
    end

    methods (Access = public)

        % Construct app
        function app = BW_GUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end